classdef fnc1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HeadlineEditFieldLabel  matlab.ui.control.Label
        HeadlineEditField       matlab.ui.control.EditField
        PredictButton           matlab.ui.control.Button
        Result                  matlab.ui.control.Label
        ResultLabel             matlab.ui.control.Label
        FakeNewsdetectionVerificationLabel  matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        ArticleEditFieldLabel   matlab.ui.control.Label
        ArticleEditField        matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.Result.Text = "Loading...";
            app.Lamp.Color = "white";
            load('demoObjects.mat');
            thetext = app.ArticleEditField.Value;
           
            result = doesAgree(app.HeadlineEditField.Value, thetext, model, sents);
            app.Result.Text = result;
            if(result == "agree")
                app.Lamp.Color = "green";
            elseif(result == "discuss")
                app.Lamp.Color = "yellow";
            else
                app.Lamp.Color = "red";
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.HeadlineEditFieldLabel = uilabel(app.UIFigure);
            app.HeadlineEditFieldLabel.Text = 'Headline';
            app.HeadlineEditField = uieditfield(app.UIFigure, 'text');
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.Result = uilabel(app.UIFigure);
            app.Result.Text = '';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.FakeNewsdetectionVerificationLabel = uilabel(app.UIFigure);
            app.FakeNewsdetectionVerificationLabel.Text = 'Fake News detection Verification';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.ArticleEditFieldLabel = uilabel(app.UIFigure);
            app.ArticleEditFieldLabel.Text = 'Article';
            app.ArticleEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = fnc1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end